classdef GUI_FPGA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResetUARTButton              matlab.ui.control.Button
        AutomaticloadingSwitch       matlab.ui.control.RockerSwitch
        AutomaticloadingSwitchLabel  matlab.ui.control.Label
        Image5                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        GreenIDFLabel                matlab.ui.control.Label
        RedEnemyLabel                matlab.ui.control.Label
        DeclarationLabel             matlab.ui.control.Label
        UARTBaud115200BpsLabel       matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        EliaIfrachLabel              matlab.ui.control.Label
        ProjectGUILabel              matlab.ui.control.Label
        ManualloadingButton          matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ManualloadingButton
        function ManualloadingButtonPushed(app, event)
                        % Create a serial object
s = serial('COM5');

% Set the baud rate, data type, stop bits, parity, and flow control
s.BaudRate = 115200;
s.DataBits = 8;
s.StopBits = 1;
s.Parity = 'none';
s.FlowControl = 'none';

% Open the serial connection
fopen(s);

% Pre-allocate array for data
num_samples = 2048;
data = zeros(num_samples, 1, 'uint32');

% Loop to read data from the serial port
for i = 1 : ceil(num_samples/128)
    if(i == 1)
        % Send command to start data acquisition
        fwrite(s, 1, 'uint8');
        % Wait for data to be available
        pause(0.1)
        flag = fread(s, 1, 'uint8');
        pause(0.03)
        % Read first block of data
        if(s.BytesAvailable > 0)
            data((i-1)*128+1:i*128) = fread(s, 128, 'uint32');
        end

        pause(0.05) 
    else        
        % Read next block of data
        if(s.BytesAvailable > 0)
            data((i-1)*128+1:i*128) = fread(s, 128, 'uint32');
        end
        pause(0.05)

    end
end

% Close the serial connection
fclose(s);
% Plot the data

freq = (1:length(data)) * 6103;
if(flag == 0)
    plot(app.UIAxes,freq,data,'r');
else
    plot(app.UIAxes,freq,data,'g');
end
        end

        % Value changed function: AutomaticloadingSwitch
        function AutomaticloadingSwitchValueChanged(app, event)

                                    % Create a serial object
s = serial('COM5');

% Set the baud rate, data type, stop bits, parity, and flow control
s.BaudRate = 115200;
s.DataBits = 8;
s.StopBits = 1;
s.Parity = 'none';
s.FlowControl = 'none';
value = app.AutomaticloadingSwitch.Value
while(value == "On")
% Open the serial connection
fopen(s);

% Pre-allocate array for data
num_samples = 2048;
data = zeros(num_samples, 1, 'uint32');

% Loop to read data from the serial port
for i = 1 : ceil(num_samples/128)
    if(i == 1)
        % Send command to start data acquisition
        fwrite(s, 1, 'uint8');
        % Wait for data to be available
        pause(0.1)
        flag = fread(s, 1, 'uint8');
        pause(0.03)
        % Read first block of data
        if(s.BytesAvailable > 0)
            data((i-1)*128+1:i*128) = fread(s, 128, 'uint32');
        end

        pause(0.05) 
    else        
        % Read next block of data
        if(s.BytesAvailable > 0)
            data((i-1)*128+1:i*128) = fread(s, 128, 'uint32');
        end
        pause(0.05)

    end
end

% Close the serial connection
fclose(s);
% Plot the data

freq = (1:length(data)) * 6103;
if(flag == 0)
    plot(app.UIAxes,freq,data,'r');
else
    plot(app.UIAxes,freq,data,'g');
end
value = app.AutomaticloadingSwitch.Value
pause(0.1)
end
        end

        % Button pushed function: ResetUARTButton
        function ResetUARTButtonPushed(app, event)
            delete(instrfind)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FFT Data')
            xlabel(app.UIAxes, 'Frequency[Mhz] ')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 1000000 2000000 3000000 4000000 5000000 6000000 7000000 8000000 9000000 10000000 11000000 12000000 13000000];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'IDF-removebg-preview.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Hatal-removebg-preview.png';
            app.ManualloadingButton = uibutton(app.UIFigure, 'push');
            app.ManualloadingButton.ButtonPushedFcn = createCallbackFcn(app, @ManualloadingButtonPushed, true);
            app.ManualloadingButton.Icon = 'Play.png';
            app.ManualloadingButton.Text = 'Manual loading';
            app.ProjectGUILabel = uilabel(app.UIFigure);
            app.ProjectGUILabel.Text = 'Project GUI';
            app.EliaIfrachLabel = uilabel(app.UIFigure);
            app.EliaIfrachLabel.Text = 'Elia Ifrach - אליה יפרח';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'IDF_GOC-removebg-preview.png';
            app.UARTBaud115200BpsLabel = uilabel(app.UIFigure);
            app.UARTBaud115200BpsLabel.Text = 'UART Baud - 115200[Bps]';
            app.DeclarationLabel = uilabel(app.UIFigure);
            app.DeclarationLabel.Text = {'Declaration:'; ''};
            app.RedEnemyLabel = uilabel(app.UIFigure);
            app.RedEnemyLabel.Text = 'Red - Enemy';
            app.GreenIDFLabel = uilabel(app.UIFigure);
            app.GreenIDFLabel.Text = 'Green - IDF';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'terror.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'solider.png';
            app.AutomaticloadingSwitchLabel = uilabel(app.UIFigure);
            app.AutomaticloadingSwitchLabel.Text = {'Automatic loading'; ''};
            app.AutomaticloadingSwitch = uiswitch(app.UIFigure, 'rocker');
            app.AutomaticloadingSwitch.ValueChangedFcn = createCallbackFcn(app, @AutomaticloadingSwitchValueChanged, true);
            app.ResetUARTButton = uibutton(app.UIFigure, 'push');
            app.ResetUARTButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUARTButtonPushed, true);
            app.ResetUARTButton.Text = 'Reset UART';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_FPGA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end